classdef mandelbrotFractals < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        maxcountofiterationsEditField_2  matlab.ui.control.NumericEditField
        maxcountofiterationsEditField_2Label  matlab.ui.control.Label
        maxcountofiterationsEditField   matlab.ui.control.NumericEditField
        maxcountofiterationsEditFieldLabel  matlab.ui.control.Label
        ResetcenterButton_2             matlab.ui.control.Button
        ZoomMandelbrotSlider            matlab.ui.control.Slider
        ZoomMandelbrotSliderLabel       matlab.ui.control.Label
        ComplexconstantDropDown         matlab.ui.control.DropDown
        ComplexconstantDropDownLabel    matlab.ui.control.Label
        ImEditField_2                   matlab.ui.control.NumericEditField
        ImEditField_2Label              matlab.ui.control.Label
        ReEditField_2                   matlab.ui.control.NumericEditField
        ReEditField_2Label              matlab.ui.control.Label
        SetcenterforJuliasetLabel       matlab.ui.control.Label
        DrawJuliaButton                 matlab.ui.control.Button
        DrawMandelbrotButton            matlab.ui.control.Button
        ResetcenterButton               matlab.ui.control.Button
        ImEditField                     matlab.ui.control.NumericEditField
        ImEditFieldLabel                matlab.ui.control.Label
        ReEditField                     matlab.ui.control.NumericEditField
        ReEditFieldLabel                matlab.ui.control.Label
        ZoomJuliaSlider                 matlab.ui.control.Slider
        ZoomJuliaSliderLabel            matlab.ui.control.Label
        ResetgraphsButton               matlab.ui.control.Button
        SetcenterforMandelbrotsetLabel  matlab.ui.control.Label
        AppforfractalsLabel             matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ZoomJuliaSlider
        function ZoomJuliaSliderValueChanged(app, event)

        end

        % Button pushed function: ResetgraphsButton
        function ResetgraphsButtonPushed(app, event)
             cla (app.UIAxes, 'reset');
             cla (app.UIAxes2, 'reset');
        end

        % Button pushed function: DrawMandelbrotButton
        function DrawMandelbrotButtonPushed(app, event)

            n=500;
            maxIter=app.maxcountofiterationsEditField.Value;
            tolerance=1.0e6;
            colscale=128;

            getNum=@(z,c)(z^2-c);
            colormap(app.UIAxes, gray(colscale));
            
            zoom = app.ZoomMandelbrotSlider.Value;
            
            reCenter = app.ReEditField.Value;
            imCenter = app.ImEditField.Value;

            xmin = (reCenter + -1)/zoom;
            xmax = (reCenter + 2)/zoom;
            ymin = (imCenter + -1)/zoom;
            ymax = (imCenter + 1)/zoom;

            
            deltax=xmax-xmin;
            deltay=ymax-ymin;
             
            fractal=zeros(n,n);
             
            for j=1:n+1
                for k=1:n+1
                    iteration=0;
                    x=xmin+deltax*(j-1)/n;
                    y=ymin+deltay*(k-1)/n;
                    c=x+1i*y;
                    z=getNum(0,c);

                    while norm(z)<tolerance && iteration<maxIter 
                        z=getNum(z,c);
                        iteration=iteration+1;
                    end
                    if norm(z)>tolerance || isinf(z) || isnan(z)
                        fractal(k,j)=round(colscale*iteration/maxIter);
                    end
                end
            end
      
            image(app.UIAxes, fractal)
            axis(app.UIAxes, 'off')
            colormap(app.UIAxes, 'default');

% neoptimalizovany algoritmus vykreslovani            

%             MAX_NUM_OF_COMPUTATIONS = 200;
%             DRAW_AROUND_RE = -1.4;
%             DRAW_AROUND_IM = 0;
%             RE_MIN = -2;
%             RE_MAX = 1;
%             IM_MIN = -1;
%             IM_MAX = 1;
% 
%             zoom = app.ZoomJuliaSlider.Value;
%             step = 0.01;
%             realPartPlot = DRAW_AROUND_RE+(RE_MIN-DRAW_AROUND_RE/zoom):(step/zoom):DRAW_AROUND_RE+(RE_MAX-DRAW_AROUND_RE/zoom);
%             imaginaryPartPlot = DRAW_AROUND_IM+(IM_MIN/zoom):(step/zoom):DRAW_AROUND_IM+(IM_MAX/zoom);
% 
%             hold (app.UIAxes, 'on')
%             %set(app.UIAxes,'color', 'blue');
% 
%             for re = realPartPlot
%                 for im = imaginaryPartPlot
%                     c = re + im*1i;
%                     deviation = mandelbrotCheck(c, MAX_NUM_OF_COMPUTATIONS);
%                     if(deviation == MAX_NUM_OF_COMPUTATIONS)
%                         plot(app.UIAxes, re, im, 'black.')      
%                     elseif(deviation > 2*(MAX_NUM_OF_COMPUTATIONS/3) && deviation < MAX_NUM_OF_COMPUTATIONS)
%                         plot(app.UIAxes, re, im, 'red.')
%                     elseif(deviation > MAX_NUM_OF_COMPUTATIONS/2 && deviation < 2*(MAX_NUM_OF_COMPUTATIONS/3))
%                         plot(app.UIAxes, re, im, 'magenta.')
%                     elseif(deviation > MAX_NUM_OF_COMPUTATIONS/3 && deviation < MAX_NUM_OF_COMPUTATIONS/2)
%                         plot(app.UIAxes, re, im, 'yellow.')
%                     elseif(deviation > MAX_NUM_OF_COMPUTATIONS/8 && deviation < MAX_NUM_OF_COMPUTATIONS/3)
%                         plot(app.UIAxes, re, im, 'green.')
%                     elseif(deviation > MAX_NUM_OF_COMPUTATIONS/16 && deviation < MAX_NUM_OF_COMPUTATIONS/8)
%                         plot(app.UIAxes, re, im, 'cyan.')
%                     end
%                 end
%             end
        end

        % Button pushed function: DrawJuliaButton
        function DrawJuliaButtonPushed(app, event)
            
            % process of extracting data from the dropdown box (it works)
            expression = app.ComplexconstantDropDown.Value;
            expressionCut = strsplit(expression, "+");
            realPart = str2double(expressionCut(1));
            foo = string(expressionCut(2));
            expressionCut2 = strsplit(foo(1), "*");
            imPart1 = str2double(expressionCut2(1));
            imPart2 = str2double(expressionCut2(2));

            num = realPart + imPart1 * imPart2;
            
            zoom = app.ZoomJuliaSlider.Value;

            colscale=128;
            colormap(app.UIAxes2, gray(colscale));

            col=app.maxcountofiterationsEditField_2.Value*zoom;
            pointCount=500;
            numRealCenter=app.ReEditField_2.Value;
            numImCenter=app.ImEditField_2.Value;
            l=1.5/zoom;

            x=linspace(numRealCenter-l,numRealCenter+l,pointCount);
            y=linspace(numImCenter-l,numImCenter+l,pointCount);
            [X,Y]=meshgrid(x,y);

            Z=X+1i*Y;

            for k=1:col
                Z=Z.^2+num;
                fractal=exp(-abs(Z));
            end
            
            % defaultni colormap je celkem humus
            colormap(app.UIAxes2, jet(256));
            pcolor(app.UIAxes2, fractal);
            shading (app.UIAxes2, 'flat');
            axis(app.UIAxes2, 'square','equal','off');
        end

        % Button pushed function: ResetcenterButton
        function ResetcenterButtonPushed(app, event)
            defaultValue = 0;
            app.ReEditField.Value = defaultValue;
            app.ImEditField.Value = defaultValue;
        end

        % Button pushed function: ResetcenterButton_2
        function ResetcenterButton_2Pushed(app, event)
            defaultValue = 0;
            app.ReEditField_2.Value = defaultValue;
            app.ImEditField_2.Value = defaultValue;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Julia fractals')
            xlabel(app.UIAxes2, 'Re')
            ylabel(app.UIAxes2, {'Im'; ''})
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, {'Mandelbrot fractals'; ''})
            xlabel(app.UIAxes, 'Re')
            ylabel(app.UIAxes, 'Im')
            zlabel(app.UIAxes, 'Z')
            app.AppforfractalsLabel = uilabel(app.UIFigure);
            app.AppforfractalsLabel.Text = 'App for fractals';
            app.SetcenterforMandelbrotsetLabel = uilabel(app.UIFigure);
            app.SetcenterforMandelbrotsetLabel.Text = 'Set center for Mandelbrot set:';
            app.ResetgraphsButton = uibutton(app.UIFigure, 'push');
            app.ResetgraphsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetgraphsButtonPushed, true);
            app.ResetgraphsButton.Text = 'Reset graphs';
            app.ZoomJuliaSliderLabel = uilabel(app.UIFigure);
            app.ZoomJuliaSliderLabel.Text = 'Zoom Julia';
            app.ZoomJuliaSlider = uislider(app.UIFigure);
            app.ZoomJuliaSlider.Limits = [1 1000];
            app.ZoomJuliaSlider.ValueChangedFcn = createCallbackFcn(app, @ZoomJuliaSliderValueChanged, true);
            app.ZoomJuliaSlider.Value = 1;
            app.ReEditFieldLabel = uilabel(app.UIFigure);
            app.ReEditFieldLabel.Text = 'Re';
            app.ReEditField = uieditfield(app.UIFigure, 'numeric');
            app.ImEditFieldLabel = uilabel(app.UIFigure);
            app.ImEditFieldLabel.Text = 'Im';
            app.ImEditField = uieditfield(app.UIFigure, 'numeric');
            app.ResetcenterButton = uibutton(app.UIFigure, 'push');
            app.ResetcenterButton.ButtonPushedFcn = createCallbackFcn(app, @ResetcenterButtonPushed, true);
            app.ResetcenterButton.Text = 'Reset center';
            app.DrawMandelbrotButton = uibutton(app.UIFigure, 'push');
            app.DrawMandelbrotButton.ButtonPushedFcn = createCallbackFcn(app, @DrawMandelbrotButtonPushed, true);
            app.DrawMandelbrotButton.Text = 'Draw Mandelbrot';
            app.DrawJuliaButton = uibutton(app.UIFigure, 'push');
            app.DrawJuliaButton.ButtonPushedFcn = createCallbackFcn(app, @DrawJuliaButtonPushed, true);
            app.DrawJuliaButton.Text = ' Draw Julia';
            app.SetcenterforJuliasetLabel = uilabel(app.UIFigure);
            app.SetcenterforJuliasetLabel.Text = 'Set center for Julia set:';
            app.ReEditField_2Label = uilabel(app.UIFigure);
            app.ReEditField_2Label.Text = 'Re';
            app.ReEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.ImEditField_2Label = uilabel(app.UIFigure);
            app.ImEditField_2Label.Text = 'Im';
            app.ImEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.ComplexconstantDropDownLabel = uilabel(app.UIFigure);
            app.ComplexconstantDropDownLabel.Text = 'Complex constant:';
            app.ComplexconstantDropDown = uidropdown(app.UIFigure);
            app.ComplexconstantDropDown.Items = {'-0.745429+0*1i', '-1.3+0.1*1i', '0.27334+-0.00742*1i', '0.11031031+-0.67037*1i', '-0.488679+-0.56790*1i', '-0.561321+0.641000*1i', '-0.745429+0.11308*1i'};
            app.ComplexconstantDropDown.Value = '-0.745429+0*1i';
            app.ZoomMandelbrotSliderLabel = uilabel(app.UIFigure);
            app.ZoomMandelbrotSliderLabel.Text = 'Zoom Mandelbrot';
            app.ZoomMandelbrotSlider = uislider(app.UIFigure);
            app.ZoomMandelbrotSlider.Limits = [1 1000];
            app.ZoomMandelbrotSlider.Value = 1;
            app.ResetcenterButton_2 = uibutton(app.UIFigure, 'push');
            app.ResetcenterButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetcenterButton_2Pushed, true);
            app.ResetcenterButton_2.Text = 'Reset center';
            app.maxcountofiterationsEditFieldLabel = uilabel(app.UIFigure);
            app.maxcountofiterationsEditFieldLabel.Text = 'max count of iterations';
            app.maxcountofiterationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.maxcountofiterationsEditField.Value = 200;
            app.maxcountofiterationsEditField_2Label = uilabel(app.UIFigure);
            app.maxcountofiterationsEditField_2Label.Text = 'max count of iterations';
            app.maxcountofiterationsEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.maxcountofiterationsEditField_2.Value = 30;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mandelbrotFractals

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end